classdef FIWA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        filtrineltempoTab               matlab.ui.container.Tab
        salvaimmagineButton_2           matlab.ui.control.Button
        selezionaimmagineButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        filtromediaTab                  matlab.ui.container.Tab
        visualizzafiltroButton_ALPS     matlab.ui.control.Button
        grandezzamatriceSpinner_ALPS    matlab.ui.control.Spinner
        grandezzamatriceSpinner_2Label  matlab.ui.control.Label
        filtrogaussianoTab              matlab.ui.container.Tab
        visualizzafiltroButton_GLPS     matlab.ui.control.Button
        grandezzamatriceSpinner_GLPS    matlab.ui.control.Spinner
        grandezzamatriceSpinnerLabel    matlab.ui.control.Label
        sigmaSlider_GLPS                matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        filtromedianoTab                matlab.ui.container.Tab
        grandezzamatriceSpinner_MLPS    matlab.ui.control.Spinner
        grandezzamatriceSpinner_3Label  matlab.ui.control.Label
        filtripassaaltoTab              matlab.ui.container.Tab
        applicafiltroButton_HPS         matlab.ui.control.Button
        enhancementdellimmagineButton_HPS  matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CannyButton                     matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        LaplacianoButton                matlab.ui.control.RadioButton
        UIAxes_output_spatial           matlab.ui.control.UIAxes
        UIAxes_input_spatial            matlab.ui.control.UIAxes
        filtriinfrequenzaTab            matlab.ui.container.Tab
        visualizzafiltroButton_Frequency  matlab.ui.control.Button
        salvaimmagineButton_Frequency   matlab.ui.control.Button
        selezionaimmagineButton_Frequency  matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        idealelpTab                     matlab.ui.container.Tab
        RaggioSpinner_ILPF              matlab.ui.control.Spinner
        RaggioSpinnerLabel              matlab.ui.control.Label
        gaussianolpTab                  matlab.ui.container.Tab
        sigmaSlider_GLPF                matlab.ui.control.Slider
        sigmaSlider_2Label              matlab.ui.control.Label
        butterworthlpTab                matlab.ui.container.Tab
        SigmaSpinner_BLPF               matlab.ui.control.Spinner
        SigmaSpinner_3Label             matlab.ui.control.Label
        ordineSpinner_BLPF              matlab.ui.control.Spinner
        ordineSpinnerLabel              matlab.ui.control.Label
        idealehpTab                     matlab.ui.container.Tab
        RaggioSpinner_IHPF              matlab.ui.control.Spinner
        RaggioSpinner_2Label            matlab.ui.control.Label
        enhancementdellimmagineButton_IHPF  matlab.ui.control.Button
        visualizzazioneButton_IHPF      matlab.ui.control.Button
        applicafiltroButton_IHPF        matlab.ui.control.Button
        gaussianohpTab                  matlab.ui.container.Tab
        enhancementdellimmagineButton_GHPF  matlab.ui.control.Button
        Visualizzazione2Button_GHPF     matlab.ui.control.Button
        ApplicafiltroButton_GHPF        matlab.ui.control.Button
        SigmaSpinner                    matlab.ui.control.Spinner
        SigmaSpinnerLabel               matlab.ui.control.Label
        butterworthhpTab                matlab.ui.container.Tab
        SigmaSpinner_BHPF               matlab.ui.control.Spinner
        SigmaSpinner_2Label             matlab.ui.control.Label
        enhacementdellimmagineButton_BHPF  matlab.ui.control.Button
        visualizzazione2Button_BHPF     matlab.ui.control.Button
        applicafiltroButton_BHPF        matlab.ui.control.Button
        OrdineSpinner_BHPF              matlab.ui.control.Spinner
        OrdineSpinnerLabel              matlab.ui.control.Label
        UIAxes_output_frequency         matlab.ui.control.UIAxes
        UIAxes_input_frequency          matlab.ui.control.UIAxes
        trasformatawaveletTab           matlab.ui.container.Tab
        SalvaimmagineButton             matlab.ui.control.Button
        SelezionaimmaginedasalvareDropDown  matlab.ui.control.DropDown
        SelezionaimmaginedasalvareDropDownLabel  matlab.ui.control.Label
        visualizzamotherwaveletButton   matlab.ui.control.Button
        eseguiButton                    matlab.ui.control.Button
        selezionalafamigliadellawaveletButtonGroup  matlab.ui.container.ButtonGroup
        FejerKorovkinfiltersButton      matlab.ui.control.RadioButton
        SymletsButton                   matlab.ui.control.RadioButton
        CoifletsButton                  matlab.ui.control.RadioButton
        DaubechiesButton                matlab.ui.control.RadioButton
        selezionaimmagineButton_3       matlab.ui.control.Button
        UIAxes_HH                       matlab.ui.control.UIAxes
        UIAxes_LH                       matlab.ui.control.UIAxes
        UIAxes_HL                       matlab.ui.control.UIAxes
        UIAxes_LL                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imInputSpatial % input image for spatial filtering 
        imOutput % output image 
        lastUsedFilter_Spatial = fspecial("average");
        
        imInputFrequency % input image for frequency filtering
        lastUsedFilter_Frequency = zeros(256,256);
        currentTitle = ""

        imInputWavelet
        
        LL
        HL
        LH
        HH


        matrixSize = 3 %inizializzo un valore di default per evitare che vada in errore
        sigma = 3 %inizializzo un valore di default per evitare che vada in errore
        currentFilter = "laplaciano" %serve per tenere traccia del filtro passa alto corrente 
        currentWavelet = 'db2'
        raggio= 35
        sigmag=3
        sigmab=35   
        ordine=1
        savePath
        matrice
    end
    
    methods (Access = private)
        
        function y =  distmatrix(app,M,N)
            
                    
                    u = 0:(M - 1);
                    v = 0:(N - 1);
                    
                    ind_u = find(u > M/2);
                    u(ind_u) = u(ind_u) - M;
                    ind_v = find(v > N/2);
                    v(ind_v) = v(ind_v) - N;
                    
                    [V, U] = meshgrid(v, u);
                    
                    %calculate distance matrix
                    y = sqrt((U .^ 2) + (V .^ 2));
        end
    
        
        function [res] = filtro_ideale(app,I,dist)
            I(I <= dist) = 1;
            I(I > dist) = 0;
            res = I;
         
        end
        
        function [res] = filtro_gaussiano_frequenza(app,imageA,sigma)
            [hA,  lA] = size(imageA);
            dist = distmatrix(app,hA,lA);
            res = exp(-(dist.^2)/(2*sigma^2));
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selezionaimmagineButton
        function selezionaimmagineButtonPushed_Spatial(app, event)
            app.UIAxes_input_spatial.Visible = 'off';
            app.UIAxes_input_spatial.Colormap = gray(256);
            axis(app.UIAxes_input_spatial, 'image');
            % Display uigetfile dialog
            app.UIAxes_output_spatial.Visible = 'off';
            app.UIAxes_output_spatial.Colormap = gray(256);
            axis(app.UIAxes_output_spatial, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInputSpatial = imread(fname) ;
            imagesc(app.UIAxes_input_spatial,app.imInputSpatial);
        end

        % Button pushed function: salvaimmagineButton_2
        function salvaimmagineButtonPushed_Spatial(app, event)
            [file,path] = uiputfile('.bmp');
            file_name = fullfile(path,file);
            imwrite(app.imOutput,file_name,"bmp");
        end

        % Value changing function: grandezzamatriceSpinner_ALPS
        function grandezzamatriceSpinnerValueChanging_ALPS(app, event)
            changingValue = event.Value;
            close all
           app.lastUsedFilter_Spatial = fspecial('average', changingValue); 
           % apply the average filter to the image
           I_average = imfilter(app.imInputSpatial, app.lastUsedFilter_Spatial);                
           app.imOutput=uint8(I_average);
           imagesc(app.UIAxes_output_spatial,app.imOutput);
        end

        % Callback function
        function grandezzamatriceEditFieldValueChanged_ALPS(app, event)
            value = app.grandezzamatriceEditField.Value;
            app.matrixSize = value;
        end

        % Button pushed function: visualizzafiltroButton_ALPS
        function visualizzafiltroButton_ALPSPushed_ALPS(app, event)
           close all
           figure;
           bar3(app.lastUsedFilter_Spatial);
           title("Filtro media")
        end

        % Value changing function: sigmaSlider_GLPS
        function sigmaSliderValueChanging_GLSP(app, event)
            changingValue = event.Value;

            dim_gauss_filter = [app.matrixSize app.matrixSize]; % dim of filter
            gauss_variance = changingValue; % variance
            app.lastUsedFilter_Spatial = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            app.imOutput = imfilter(app.imInputSpatial, app.lastUsedFilter_Spatial);
            
            imagesc(app.UIAxes_output_spatial,app.imOutput);
        end

        % Value changed function: sigmaSlider_GLPS
        function sigmaSliderValueChanged_GLPS(app, event)
            value = app.sigmaSlider_GLPS.Value;
            app.sigma = value;
        end

        % Value changed function: grandezzamatriceSpinner_GLPS
        function grandezzamatriceSpinnerValueChanged_GLPS(app, event)
            value = app.grandezzamatriceSpinner_GLPS.Value;
            app.matrixSize = value;
        end

        % Value changing function: grandezzamatriceSpinner_GLPS
        function grandezzamatriceSpinnerValueChanging_GLPS(app, event)
            changingValue = event.Value;
            app.matrixSize = changingValue;
            dim_gauss_filter = [app.matrixSize  app.matrixSize ]; % dim of filter
            gauss_variance = app.sigma; % variance
            app.lastUsedFilter_Spatial = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            app.imOutput = imfilter(app.imInputSpatial, app.lastUsedFilter_Spatial);
            imagesc(app.UIAxes_output_spatial,app.imOutput);
        end

        % Button pushed function: visualizzafiltroButton_GLPS
        function visualizzafiltroButtonPushed_GLPS(app, event)
            close all
            figure
            bar3(app.lastUsedFilter_Spatial);
            title("Filtro gaussiano")
        end

        % Value changing function: grandezzamatriceSpinner_MLPS
        function grandezzamatriceSpinnerValueChanging_MLPS(app, event)
            changingValue = event.Value;
            app.matrixSize = changingValue;
            [a,b,c] = size(app.imInputSpatial);
            app.imOutput = zeros(a,b,c);
            for i = 1:c
                tmp = medfilt2(app.imInputSpatial(:,:,i), [app.matrixSize app.matrixSize]);
                app.imOutput(:,:,i) = tmp;
            end
            imagesc(app.UIAxes_output_spatial,uint8(app.imOutput));
             
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged_HPS(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Laplaciano'
                    app.currentFilter = "laplaciano";
                case 'Prewitt'
                    app.currentFilter = "prewitt";
                case 'Sobel'
                    app.currentFilter = "sobel"; 
                case 'Canny'
                    app.currentFilter = "canny";
            end
        end

        % Button pushed function: enhancementdellimmagineButton_HPS
        function enhancedImmagineButtonPushed_HPS(app, event)
            double_input = double(app.imInputSpatial);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    filtrata = imfilter(double_input,filter);
                    app.imOutput = uint8(double_input - filtrata);
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    app.imOutput = uint8(double_input + filtered);
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    app.imOutput = uint8(filtered + double_input);
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "canny"
                    filtered = edge(double_input,'canny');
                    app.imOutput = uint8(filtered );
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
            end
        end

        % Button pushed function: applicafiltroButton_HPS
        function applicafiltroButtonPushed_HPS(app, event)
            double_input = double(app.imInputSpatial);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    app.imOutput = uint8(imfilter(double_input,filter));
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    app.imOutput = uint8(filtered);
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    app.imOutput = uint8(filtered);
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
                case "canny"
                    app.imOutput = uint8(edge(double_input,'canny'));
                    imagesc(app.UIAxes_output_spatial,app.imOutput);
            end
        end

        % Button pushed function: selezionaimmagineButton_Frequency
        function selezionaimmagineButtonPushed_Frequency(app, event)
            app.UIAxes_input_frequency.Visible = 'off';
            app.UIAxes_input_frequency.Colormap = gray(256);
            axis(app.UIAxes_input_frequency, 'image');
            % Display uigetfile dialog
            app.UIAxes_output_frequency.Visible = 'off';
            app.UIAxes_output_frequency.Colormap = gray(256);
            axis(app.UIAxes_output_frequency, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInputFrequency=imread(fname) ;
            imagesc(app.UIAxes_input_frequency,app.imInputFrequency);
        end

        % Button pushed function: salvaimmagineButton_Frequency
        function salvaimmagineButtonPushed_Frequency(app, event)
            [file,path] = uiputfile('.bmp');
            file_name = fullfile(path,file);
            imwrite(app.imOutput,file_name,"bmp");
        end

        % Button pushed function: visualizzafiltroButton_Frequency
        function visualizzafiltroButtonPushed_Frequency(app, event)
            close all
            figure
            imshow(app.lastUsedFilter_Frequency);
            title(app.currentTitle);
        end

        % Value changed function: RaggioSpinner_ILPF
        function RaggioSpinnerValueChanged_ILPF(app, event)
            value = app.RaggioSpinner_ILPF.Value;
            app.raggio=value;
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            matrix= app.distmatrix(hA,lA);
            mask = app.filtro_ideale(matrix,app.raggio);
            app.lastUsedFilter_Frequency = fftshift(mask);
            app.currentTitle = "filtro ideale passa basso";
            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput)               
        end

        % Value changed function: sigmaSlider_GLPF
        function sigmaSliderValueChanged_GLPF(app, event)
            value = app.sigmaSlider_GLPF.Value;
            app.sigmag=value;
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            gaussiano = app.filtro_gaussiano_frequenza(app.imInputFrequency,app.sigmag);
            
            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa basso";
            
            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changing function: ordineSpinner_BLPF
        function ordineSpinnerValueChanging_BLPF(app, event)
            changingValue = event.Value;
            app.ordine=changingValue;  
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1./(1+(dist./app.sigmab).^(2*app.ordine));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro butterworth passa basso";

            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changing function: SigmaSpinner_BLPF
        function SigmaSpinnerValueChanging_BLPF(app, event)
            changingValue = event.Value;
            app.sigmab=changingValue;  
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1./(1+(dist./app.sigmab).^(2*app.ordine));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro butterworth passa basso";

            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Button pushed function: visualizzazioneButton_IHPF
        function visualizzazioneButtonPushed_IHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
            
            app.lastUsedFilter_Frequency = fftshift(mask);
            app.currentTitle = "filtro ideale passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changed function: RaggioSpinner_IHPF
        function RaggioSpinnerValueChanged_IHPF(app, event)
            app.raggio = app.RaggioSpinner_IHPF.Value;
        end

        % Button pushed function: applicafiltroButton_IHPF
        function applicafiltroButtonPushed_IHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));

            app.lastUsedFilter_Frequency = fftshift(mask);
            app.currentTitle = "filtro ideale passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changing function: RaggioSpinner_IHPF
        function RaggioSpinnerValueChanging_IHPF(app, event)
            app.raggio = event.Value;
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
            
            app.lastUsedFilter_Frequency = fftshift(mask);
            app.currentTitle = "filtro ideale passa alto";

        end

        % Button pushed function: enhancementdellimmagineButton_IHPF
        function enhancementdellimmagineButtonPushed_IHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));

            app.lastUsedFilter_Frequency = fftshift(mask);
            app.currentTitle = "filtro ideale passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInputFrequency + imageB;
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChanged_GHPF(app, event)
            app.sigmag = app.SigmaSpinner.Value;

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";
        end

        % Value changing function: SigmaSpinner
        function SigmaSpinnerValueChanging_GHPF(app, event)
            app.sigmag = event.Value;     

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));
            
            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";
        end

        % Button pushed function: ApplicafiltroButton_GHPF
        function ApplicafiltroButtonPushed_GHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Button pushed function: Visualizzazione2Button_GHPF
        function Visualizzazione2ButtonPushed_GHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Button pushed function: enhancementdellimmagineButton_GHPF
        function enhancementdellimmagineButtonPushed_GHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";

            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInputFrequency + imageB;
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changed function: OrdineSpinner_BHPF
        function OrdineSpinnerValueChanged_BHPF(app, event)
            app.ordine = app.OrdineSpinner_BHPF.Value; 

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro passa alto butterworth";
        end

        % Value changing function: OrdineSpinner_BHPF
        function OrdineSpinnerValueChanging_BHPF(app, event)
            app.ordine = event.Value;       

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro passa alto butterworth";
        end

        % Button pushed function: applicafiltroButton_BHPF
        function applicafiltroButtonPushed_BHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera 
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro passa alto butterworth";

            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Button pushed function: visualizzazione2Button_BHPF
        function visualizzazione2Buttonpushed_BHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro passa alto butterworth";

            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Button pushed function: enhacementdellimmagineButton_BHPF
        function enhacementdellimmagineButtonPushed_BHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInputFrequency);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));

            app.lastUsedFilter_Frequency = fftshift(butterworth);
            app.currentTitle = "filtro passa alto butterworth";

            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInputFrequency + imageB;
            imagesc(app.UIAxes_output_frequency,app.imOutput);
        end

        % Value changed function: SigmaSpinner_BHPF
        function SigmaSpinnerValueChanged_BHPF(app, event)
            app.sigma = app.SigmaSpinner_BHPF.Value;

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);   
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigma^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";
        end

        % Value changing function: SigmaSpinner_BHPF
        function SigmaSpinnerValueChanging_BHPF(app, event)
            app.sigma = event.Value;

            [hA,  lA] = size(app.imInputFrequency);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigma^2)));

            app.lastUsedFilter_Frequency = fftshift(gaussiano);
            app.currentTitle = "filtro gaussiano passa alto";
        end

        % Button pushed function: selezionaimmagineButton_3
        function selezionaimmagineButton_3Pushed(app, event)
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_3.Colormap = gray(256);
            axis(app.UIAxes_3, 'image');
            % Display uigetfile dialog
            app.UIAxes_LL.Visible = 'off';
            app.UIAxes_LL.Colormap = gray(256);
            axis(app.UIAxes_LL, 'image');
            app.UIAxes_HL.Visible = 'off';
            app.UIAxes_HL.Colormap = gray(256);
            axis(app.UIAxes_HL, 'image');
            app.UIAxes_LH.Visible = 'off';
            app.UIAxes_LH.Colormap = gray(256);
            axis(app.UIAxes_LH, 'image');
            app.UIAxes_HH.Visible = 'off';
            app.UIAxes_HH.Colormap = gray(256);
            axis(app.UIAxes_HH, 'image');
            
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInputWavelet=imread(fname) ;
            imagesc(app.UIAxes_3,app.imInputWavelet);
        end

        % Selection changed function: 
        % selezionalafamigliadellawaveletButtonGroup
        function selezionalafamigliadellawaveletButtonGroupSelectionChanged(app, event)
            selectedButton = app.selezionalafamigliadellawaveletButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Daubechies'
                    app.currentWavelet = 'db2';
                   
                case 'Coiflets'
                    app.currentWavelet = 'coif2';
                    
                case 'Symlets'
                    app.currentWavelet = 'sym2';

                case 'Fejer-Korovkin filters'
                    app.currentWavelet = 'fk4';
                   
            end
            
        end

        % Button pushed function: eseguiButton
        function eseguiButtonPushedWavelet(app, event)
         
                    [ll,lh,hl,hh] = dwt2(app.imInputWavelet,app.currentWavelet);

                    app.LL = ll;
                    app.LH = lh;
                    app.HL = hl;
                    app.HH = hh;

                    imagesc(app.UIAxes_LL,ll);
                    imagesc(app.UIAxes_LH,lh);
                    imagesc(app.UIAxes_HL,hl);
                    imagesc(app.UIAxes_HH,hh);


        end

        % Button pushed function: visualizzamotherwaveletButton
        function visualizzamotherwaveletButtonPushed_Wavelet(app, event)
                    close all
                    [~,psi,xval] = wavefun(app.currentWavelet,10);
                    figure
                    plot(xval,psi); 
        end

        % Button pushed function: SalvaimmagineButton
        function SalvaimmagineButtonPushedWavelet(app, event)
            [file,path] = uiputfile('.bmp');
            file_name = fullfile(path,file);
            imwrite(app.imOutput,file_name,"bmp");
        end

        % Value changed function: SelezionaimmaginedasalvareDropDown
        function SelezionaimmaginedasalvareDropDownValueChanged(app, event)
            value = app.SelezionaimmaginedasalvareDropDown.Value;
            switch value
                case 'LL'
                    app.imOutput = app.LL;
                   
                case 'LH'
                    app.imOutput = app.LH;
                    
                case 'HL'
                    app.imOutput = app.HL;

                case 'HH'
                    app.imOutput = app.HH;
                   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.filtrineltempoTab = uitab(app.TabGroup);
            app.filtrineltempoTab.Title = 'filtri nel tempo';
            app.UIAxes_input_spatial = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes_input_spatial, 'INPUT')
            app.UIAxes_input_spatial.XTick = [];
            app.UIAxes_input_spatial.YTick = [];
            app.UIAxes_input_spatial.ZTick = [];
            app.UIAxes_output_spatial = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes_output_spatial, 'OUTPUT')
            app.UIAxes_output_spatial.XLim = [0 1];
            app.UIAxes_output_spatial.XTick = [];
            app.UIAxes_output_spatial.YTick = [];
            app.TabGroup2 = uitabgroup(app.filtrineltempoTab);
            app.filtromediaTab = uitab(app.TabGroup2);
            app.filtromediaTab.Title = 'filtro media ';
            app.grandezzamatriceSpinner_2Label = uilabel(app.filtromediaTab);
            app.grandezzamatriceSpinner_2Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_ALPS = uispinner(app.filtromediaTab);
            app.grandezzamatriceSpinner_ALPS.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging_ALPS, true);
            app.grandezzamatriceSpinner_ALPS.Limits = [1 256];
            app.grandezzamatriceSpinner_ALPS.Value = 3;
            app.visualizzafiltroButton_ALPS = uibutton(app.filtromediaTab, 'push');
            app.visualizzafiltroButton_ALPS.ButtonPushedFcn = createCallbackFcn(app, @visualizzafiltroButton_ALPSPushed_ALPS, true);
            app.visualizzafiltroButton_ALPS.Text = 'visualizza filtro';
            app.filtrogaussianoTab = uitab(app.TabGroup2);
            app.filtrogaussianoTab.Title = 'filtro gaussiano';
            app.sigmaSliderLabel = uilabel(app.filtrogaussianoTab);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider_GLPS = uislider(app.filtrogaussianoTab);
            app.sigmaSlider_GLPS.Limits = [1 50];
            app.sigmaSlider_GLPS.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged_GLPS, true);
            app.sigmaSlider_GLPS.ValueChangingFcn = createCallbackFcn(app, @sigmaSliderValueChanging_GLSP, true);
            app.sigmaSlider_GLPS.Value = 5;
            app.grandezzamatriceSpinnerLabel = uilabel(app.filtrogaussianoTab);
            app.grandezzamatriceSpinnerLabel.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_GLPS = uispinner(app.filtrogaussianoTab);
            app.grandezzamatriceSpinner_GLPS.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging_GLPS, true);
            app.grandezzamatriceSpinner_GLPS.Limits = [1 256];
            app.grandezzamatriceSpinner_GLPS.ValueChangedFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanged_GLPS, true);
            app.grandezzamatriceSpinner_GLPS.Value = 10;
            app.visualizzafiltroButton_GLPS = uibutton(app.filtrogaussianoTab, 'push');
            app.visualizzafiltroButton_GLPS.ButtonPushedFcn = createCallbackFcn(app, @visualizzafiltroButtonPushed_GLPS, true);
            app.visualizzafiltroButton_GLPS.Text = 'visualizza filtro';
            app.filtromedianoTab = uitab(app.TabGroup2);
            app.filtromedianoTab.Title = 'filtro mediano';
            app.grandezzamatriceSpinner_3Label = uilabel(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_MLPS = uispinner(app.filtromedianoTab);
            app.grandezzamatriceSpinner_MLPS.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging_MLPS, true);
            app.grandezzamatriceSpinner_MLPS.Limits = [2 256];
            app.grandezzamatriceSpinner_MLPS.Value = 3;
            app.filtripassaaltoTab = uitab(app.TabGroup2);
            app.filtripassaaltoTab.Title = 'filtri passa alto ';
            app.ButtonGroup = uibuttongroup(app.filtripassaaltoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged_HPS, true);
            app.ButtonGroup.Title = 'Button Group';
            app.LaplacianoButton = uiradiobutton(app.ButtonGroup);
            app.LaplacianoButton.Text = 'Laplaciano';
            app.LaplacianoButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.ButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uiradiobutton(app.ButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.enhancementdellimmagineButton_HPS = uibutton(app.filtripassaaltoTab, 'push');
            app.enhancementdellimmagineButton_HPS.ButtonPushedFcn = createCallbackFcn(app, @enhancedImmagineButtonPushed_HPS, true);
            app.enhancementdellimmagineButton_HPS.Text = 'enhancement dell''immagine';
            app.applicafiltroButton_HPS = uibutton(app.filtripassaaltoTab, 'push');
            app.applicafiltroButton_HPS.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButtonPushed_HPS, true);
            app.applicafiltroButton_HPS.Text = 'applica filtro';
            app.selezionaimmagineButton = uibutton(app.filtrineltempoTab, 'push');
            app.selezionaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButtonPushed_Spatial, true);
            app.selezionaimmagineButton.Text = 'seleziona immagine';
            app.salvaimmagineButton_2 = uibutton(app.filtrineltempoTab, 'push');
            app.salvaimmagineButton_2.ButtonPushedFcn = createCallbackFcn(app, @salvaimmagineButtonPushed_Spatial, true);
            app.salvaimmagineButton_2.Text = 'salva immagine';
            app.filtriinfrequenzaTab = uitab(app.TabGroup);
            app.filtriinfrequenzaTab.Title = 'filtri in frequenza';
            app.UIAxes_input_frequency = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes_input_frequency, 'INPUT')
            app.UIAxes_input_frequency.XTick = [];
            app.UIAxes_input_frequency.YTick = [];
            app.UIAxes_input_frequency.ZTick = [];
            app.UIAxes_output_frequency = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes_output_frequency, 'OUTPUT')
            app.UIAxes_output_frequency.XLim = [0 1];
            app.UIAxes_output_frequency.XTick = [];
            app.UIAxes_output_frequency.YTick = [];
            app.TabGroup2_2 = uitabgroup(app.filtriinfrequenzaTab);
            app.idealelpTab = uitab(app.TabGroup2_2);
            app.idealelpTab.Title = 'ideale lp';
            app.RaggioSpinnerLabel = uilabel(app.idealelpTab);
            app.RaggioSpinnerLabel.Text = 'Raggio';
            app.RaggioSpinner_ILPF = uispinner(app.idealelpTab);
            app.RaggioSpinner_ILPF.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinnerValueChanged_ILPF, true);
            app.RaggioSpinner_ILPF.Value = 35;
            app.gaussianolpTab = uitab(app.TabGroup2_2);
            app.gaussianolpTab.Title = 'gaussiano lp';
            app.sigmaSlider_2Label = uilabel(app.gaussianolpTab);
            app.sigmaSlider_2Label.Text = 'sigma';
            app.sigmaSlider_GLPF = uislider(app.gaussianolpTab);
            app.sigmaSlider_GLPF.Limits = [1 50];
            app.sigmaSlider_GLPF.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged_GLPF, true);
            app.sigmaSlider_GLPF.Value = 5;
            app.butterworthlpTab = uitab(app.TabGroup2_2);
            app.butterworthlpTab.Title = 'butterworth lp';
            app.ordineSpinnerLabel = uilabel(app.butterworthlpTab);
            app.ordineSpinnerLabel.Text = 'ordine';
            app.ordineSpinner_BLPF = uispinner(app.butterworthlpTab);
            app.ordineSpinner_BLPF.ValueChangingFcn = createCallbackFcn(app, @ordineSpinnerValueChanging_BLPF, true);
            app.ordineSpinner_BLPF.Limits = [1 Inf];
            app.ordineSpinner_BLPF.Value = 2;
            app.SigmaSpinner_3Label = uilabel(app.butterworthlpTab);
            app.SigmaSpinner_3Label.Text = 'Sigma';
            app.SigmaSpinner_BLPF = uispinner(app.butterworthlpTab);
            app.SigmaSpinner_BLPF.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinnerValueChanging_BLPF, true);
            app.SigmaSpinner_BLPF.Limits = [1 Inf];
            app.SigmaSpinner_BLPF.Value = 35;
            app.idealehpTab = uitab(app.TabGroup2_2);
            app.idealehpTab.Title = 'ideale hp';
            app.applicafiltroButton_IHPF = uibutton(app.idealehpTab, 'push');
            app.applicafiltroButton_IHPF.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButtonPushed_IHPF, true);
            app.applicafiltroButton_IHPF.Text = 'applica filtro ';
            app.visualizzazioneButton_IHPF = uibutton(app.idealehpTab, 'push');
            app.visualizzazioneButton_IHPF.ButtonPushedFcn = createCallbackFcn(app, @visualizzazioneButtonPushed_IHPF, true);
            app.visualizzazioneButton_IHPF.Text = 'visualizzazione 2';
            app.enhancementdellimmagineButton_IHPF = uibutton(app.idealehpTab, 'push');
            app.enhancementdellimmagineButton_IHPF.ButtonPushedFcn = createCallbackFcn(app, @enhancementdellimmagineButtonPushed_IHPF, true);
            app.enhancementdellimmagineButton_IHPF.Text = 'enhancement dell''immagine';
            app.RaggioSpinner_2Label = uilabel(app.idealehpTab);
            app.RaggioSpinner_2Label.Text = 'Raggio';
            app.RaggioSpinner_IHPF = uispinner(app.idealehpTab);
            app.RaggioSpinner_IHPF.ValueChangingFcn = createCallbackFcn(app, @RaggioSpinnerValueChanging_IHPF, true);
            app.RaggioSpinner_IHPF.Limits = [1 Inf];
            app.RaggioSpinner_IHPF.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinnerValueChanged_IHPF, true);
            app.RaggioSpinner_IHPF.Value = 35;
            app.gaussianohpTab = uitab(app.TabGroup2_2);
            app.gaussianohpTab.Title = 'gaussiano hp';
            app.SigmaSpinnerLabel = uilabel(app.gaussianohpTab);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.gaussianohpTab);
            app.SigmaSpinner.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinnerValueChanging_GHPF, true);
            app.SigmaSpinner.Limits = [1 Inf];
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged_GHPF, true);
            app.SigmaSpinner.Value = 35;
            app.ApplicafiltroButton_GHPF = uibutton(app.gaussianohpTab, 'push');
            app.ApplicafiltroButton_GHPF.ButtonPushedFcn = createCallbackFcn(app, @ApplicafiltroButtonPushed_GHPF, true);
            app.ApplicafiltroButton_GHPF.Text = 'Applica filtro';
            app.Visualizzazione2Button_GHPF = uibutton(app.gaussianohpTab, 'push');
            app.Visualizzazione2Button_GHPF.ButtonPushedFcn = createCallbackFcn(app, @Visualizzazione2ButtonPushed_GHPF, true);
            app.Visualizzazione2Button_GHPF.Text = 'Visualizzazione 2';
            app.enhancementdellimmagineButton_GHPF = uibutton(app.gaussianohpTab, 'push');
            app.enhancementdellimmagineButton_GHPF.ButtonPushedFcn = createCallbackFcn(app, @enhancementdellimmagineButtonPushed_GHPF, true);
            app.enhancementdellimmagineButton_GHPF.Text = 'enhancement dell''immagine ';
            app.butterworthhpTab = uitab(app.TabGroup2_2);
            app.butterworthhpTab.Title = 'butterworth hp';
            app.OrdineSpinnerLabel = uilabel(app.butterworthhpTab);
            app.OrdineSpinnerLabel.Text = 'Ordine';
            app.OrdineSpinner_BHPF = uispinner(app.butterworthhpTab);
            app.OrdineSpinner_BHPF.ValueChangingFcn = createCallbackFcn(app, @OrdineSpinnerValueChanging_BHPF, true);
            app.OrdineSpinner_BHPF.Limits = [1 Inf];
            app.OrdineSpinner_BHPF.ValueChangedFcn = createCallbackFcn(app, @OrdineSpinnerValueChanged_BHPF, true);
            app.OrdineSpinner_BHPF.Value = 2;
            app.applicafiltroButton_BHPF = uibutton(app.butterworthhpTab, 'push');
            app.applicafiltroButton_BHPF.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButtonPushed_BHPF, true);
            app.applicafiltroButton_BHPF.Text = 'applica filtro';
            app.visualizzazione2Button_BHPF = uibutton(app.butterworthhpTab, 'push');
            app.visualizzazione2Button_BHPF.ButtonPushedFcn = createCallbackFcn(app, @visualizzazione2Buttonpushed_BHPF, true);
            app.visualizzazione2Button_BHPF.Text = 'visualizzazione 2';
            app.enhacementdellimmagineButton_BHPF = uibutton(app.butterworthhpTab, 'push');
            app.enhacementdellimmagineButton_BHPF.ButtonPushedFcn = createCallbackFcn(app, @enhacementdellimmagineButtonPushed_BHPF, true);
            app.enhacementdellimmagineButton_BHPF.Text = 'enhacement dell''immagine';
            app.SigmaSpinner_2Label = uilabel(app.butterworthhpTab);
            app.SigmaSpinner_2Label.Text = 'Sigma';
            app.SigmaSpinner_BHPF = uispinner(app.butterworthhpTab);
            app.SigmaSpinner_BHPF.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinnerValueChanging_BHPF, true);
            app.SigmaSpinner_BHPF.Limits = [1 Inf];
            app.SigmaSpinner_BHPF.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChanged_BHPF, true);
            app.SigmaSpinner_BHPF.Value = 35;
            app.selezionaimmagineButton_Frequency = uibutton(app.filtriinfrequenzaTab, 'push');
            app.selezionaimmagineButton_Frequency.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButtonPushed_Frequency, true);
            app.selezionaimmagineButton_Frequency.Text = 'seleziona immagine';
            app.salvaimmagineButton_Frequency = uibutton(app.filtriinfrequenzaTab, 'push');
            app.salvaimmagineButton_Frequency.ButtonPushedFcn = createCallbackFcn(app, @salvaimmagineButtonPushed_Frequency, true);
            app.salvaimmagineButton_Frequency.Text = 'salva immagine';
            app.visualizzafiltroButton_Frequency = uibutton(app.filtriinfrequenzaTab, 'push');
            app.visualizzafiltroButton_Frequency.ButtonPushedFcn = createCallbackFcn(app, @visualizzafiltroButtonPushed_Frequency, true);
            app.visualizzafiltroButton_Frequency.Text = 'visualizza filtro';
            app.trasformatawaveletTab = uitab(app.TabGroup);
            app.trasformatawaveletTab.Title = 'trasformata wavelet';
            app.UIAxes_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_3, 'INPUT')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_LL = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_LL, 'LL')
            app.UIAxes_LL.XLim = [0 1];
            app.UIAxes_LL.XTick = [];
            app.UIAxes_LL.YTick = [];
            app.UIAxes_HL = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_HL, 'HL')
            app.UIAxes_HL.XLim = [0 1];
            app.UIAxes_HL.XTick = [];
            app.UIAxes_HL.YTick = [];
            app.UIAxes_LH = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_LH, 'LH')
            app.UIAxes_LH.XLim = [0 1];
            app.UIAxes_LH.XTick = [];
            app.UIAxes_LH.YTick = [];
            app.UIAxes_HH = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_HH, 'HH')
            app.UIAxes_HH.XLim = [0 1];
            app.UIAxes_HH.XTick = [];
            app.UIAxes_HH.YTick = [];
            app.selezionaimmagineButton_3 = uibutton(app.trasformatawaveletTab, 'push');
            app.selezionaimmagineButton_3.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButton_3Pushed, true);
            app.selezionaimmagineButton_3.Text = 'seleziona immagine';
            app.selezionalafamigliadellawaveletButtonGroup = uibuttongroup(app.trasformatawaveletTab);
            app.selezionalafamigliadellawaveletButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selezionalafamigliadellawaveletButtonGroupSelectionChanged, true);
            app.selezionalafamigliadellawaveletButtonGroup.Title = 'seleziona la famiglia della wavelet';
            app.DaubechiesButton = uiradiobutton(app.selezionalafamigliadellawaveletButtonGroup);
            app.DaubechiesButton.Text = 'Daubechies';
            app.DaubechiesButton.Value = true;
            app.CoifletsButton = uiradiobutton(app.selezionalafamigliadellawaveletButtonGroup);
            app.CoifletsButton.Text = 'Coiflets';
            app.SymletsButton = uiradiobutton(app.selezionalafamigliadellawaveletButtonGroup);
            app.SymletsButton.Text = 'Symlets';
            app.FejerKorovkinfiltersButton = uiradiobutton(app.selezionalafamigliadellawaveletButtonGroup);
            app.FejerKorovkinfiltersButton.Text = 'Fejer-Korovkin filters';
            app.eseguiButton = uibutton(app.trasformatawaveletTab, 'push');
            app.eseguiButton.ButtonPushedFcn = createCallbackFcn(app, @eseguiButtonPushedWavelet, true);
            app.eseguiButton.Text = 'esegui';
            app.visualizzamotherwaveletButton = uibutton(app.trasformatawaveletTab, 'push');
            app.visualizzamotherwaveletButton.ButtonPushedFcn = createCallbackFcn(app, @visualizzamotherwaveletButtonPushed_Wavelet, true);
            app.visualizzamotherwaveletButton.Text = 'visualizza mother wavelet';
            app.SelezionaimmaginedasalvareDropDownLabel = uilabel(app.trasformatawaveletTab);
            app.SelezionaimmaginedasalvareDropDownLabel.Text = 'Seleziona immagine da salvare';
            app.SelezionaimmaginedasalvareDropDown = uidropdown(app.trasformatawaveletTab);
            app.SelezionaimmaginedasalvareDropDown.Items = {'LL', 'LH', 'HL', 'HH'};
            app.SelezionaimmaginedasalvareDropDown.ValueChangedFcn = createCallbackFcn(app, @SelezionaimmaginedasalvareDropDownValueChanged, true);
            app.SelezionaimmaginedasalvareDropDown.Value = 'LL';
            app.SalvaimmagineButton = uibutton(app.trasformatawaveletTab, 'push');
            app.SalvaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaimmagineButtonPushedWavelet, true);
            app.SalvaimmagineButton.Text = 'Salva immagine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIWA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end