classdef Prova_Immagini_gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        Tab2                        matlab.ui.container.Tab
        StartButton                 matlab.ui.control.Button
        OptionButtonGroup           matlab.ui.container.ButtonGroup
        FourierHpIdealeButton       matlab.ui.control.RadioButton
        FourierHpButterworthButton  matlab.ui.control.RadioButton
        FourierHpGaussianoButton    matlab.ui.control.RadioButton
        FourierLpButterworthButton  matlab.ui.control.RadioButton
        FourierLpGaussianoButton    matlab.ui.control.RadioButton
        FourierLpIdealeButton       matlab.ui.control.RadioButton
        FourierButton               matlab.ui.control.RadioButton
        EdgedetectorSobelButton     matlab.ui.control.RadioButton
        EdgedetectorPrewittButton   matlab.ui.control.RadioButton
        EdgedetectorCannyButton     matlab.ui.control.RadioButton
        LaplacianoButton            matlab.ui.control.RadioButton
        MedianoButton               matlab.ui.control.RadioButton
        GaussianoButton             matlab.ui.control.RadioButton
        NonuniformeButton           matlab.ui.control.RadioButton
        FiltromediaButton           matlab.ui.control.RadioButton
        SelectimageButton           matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFile % Description
    end
    
   methods (Access = private)
        
        function y =  distmatrix(app,M,N)
            
                    
                    u = 0:(M - 1);
                    v = 0:(N - 1);
                    
                    ind_u = find(u > M/2);
                    u(ind_u) = u(ind_u) - M;
                    ind_v = find(v > N/2);
                    v(ind_v) = v(ind_v) - N;
                    
                    [V, U] = meshgrid(v, u);
                    
                    %calculate distance matrix
                    y = sqrt((U .^ 2) + (V .^ 2));
        end
    
        
        function [res] = filtro_ideale(app,I,dist)
            I(I <= dist) = 1;
            I(I > dist) = 0;
            res = I;
        end
        
        function [res] = filtro_gaussiano_frequenza(app,imageA,sigma)
            [hA,  lA] = size(imageA);
            dist = distmatrix(app,hA,lA);
            res = exp(-(dist.^2)/(2*sigma^2));
        end 
        
        function results = func4(app,image,sigma)
                %faccio la trasformata
                fftA = fft2(image);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                gaussiano = app.filtro_gaussiano_frequenza(image,sigma);
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                results = ifft2(fftshift(fttA_filtered));

        end
   end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            % Display uigetfile dialog
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
              % Display uigetfile dialog
            app.UIAxes2.Visible = 'off';
            app.UIAxes2.Colormap = gray(256);
            axis(app.UIAxes2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.ImageFile=imread(fname) ;
            imagesc(app.UIAxes,app.ImageFile);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.FiltromediaButton.Value==true
                
                dim_filter = 6; 
                H_average = fspecial('average', dim_filter); 
                % apply the average filter to the image
                I_average = imfilter(app.ImageFile, H_average);                
                I_average=uint8(I_average);
                imagesc(app.UIAxes2,I_average);
            end
                if  app.NonuniformeButton.Value==true
                    % Definition of a diagonal filter
                    H_not_uniform = [3 0 0; 
                                    0 1 0; 
                                    0 0 3];
                    % Normalize a filter
                    H_not_uniform = H_not_uniform ./ sum(sum(H_not_uniform));
                    I_not_uniform = imfilter(app.ImageFile, H_not_uniform);
                    imagesc(app.UIAxes2,I_not_uniform);
                end
           if  app.GaussianoButton.Value==true
                    dim_gauss_filter = 4; % dim of filter
                    gauss_variance = 10; % variance
                    H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
                    I_gaussian = imfilter(app.ImageFile, H_gaussian);
                    imagesc(app.UIAxes2,I_gaussian);

           end
           if app.MedianoButton.Value==true
               % apply a median filtering to the noisy image using medfilt2
                height_neight = 2;
                width_neight = 2;
                I_denoised = medfilt2(app.ImageFile, [height_neight width_neight]);
                imagesc(app.UIAxes2,I_denoised);
           end
           if app.LaplacianoButton.Value==true
                alpha = 0.9;
                c = 0.5;
                H_laplac = fspecial("laplacian", alpha);
                I_laplac = imfilter(app.ImageFile, H_laplac);
                I_enhanced = uint8(app.ImageFile + c*I_laplac);
                % rescale the image 
                I_enhanced(I_enhanced > 255) = 255;
                I_enhanced(I_enhanced < 0) = 0;
               imagesc(app.UIAxes2,I_enhanced);
           end
           if app.EdgedetectorCannyButton.Value==true
               threshold = []; 
               imagesc(app.UIAxes2,edge(app.ImageFile, 'Canny', threshold));
               
           end
           if app.EdgedetectorPrewittButton.Value==true
               threshold = []; 
               imagesc(app.UIAxes2,edge(app.ImageFile, 'Prewitt', threshold));
           end
           if app.EdgedetectorSobelButton.Value==true
               threshold = []; 
               imagesc(app.UIAxes2,edge(app.ImageFile, 'Sobel', threshold));
           end
           if app.FourierButton.Value==true
                % compute fft of the spectrum
                F_I = fft2(app.ImageFile);
                % center the spectrum
                F_I_centered = fftshift(F_I);
                log_F_I_centered = log(1 + abs(F_I_centered));
                imagesc(app.UIAxes2,log_F_I_centered);
           end
           if app.FourierLpIdealeButton.Value==true
               %faccio la trasformata
                fftA = fft2(app.ImageFile);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.ImageFile);
                matrice= app.distmatrix(hA,lA);
                mask = app.filtro_ideale(matrice,35);
                %applico il filtro shiftato
                fttA_filtered = fftshift(mask) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2,imageB);

           end
           if app.FourierLpGaussianoButton.Value==true
               %faccio la trasformata
                fftA = fft2(app.ImageFile);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                sigma = 50;
                gaussiano = app.filtro_gaussiano_frequenza(app.ImageFile,sigma);
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2,imageB);
           end
           if app.FourierLpButterworthButton.Value==true
               %faccio la trasformata
                fftA = fft2(app.ImageFile);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                sigma = 33;
                n = 3;
                [hA,  lA] = size(app.ImageFile);
                dist = app.distmatrix(hA,lA);
                butterworth = 1./(1+(dist./sigma).^(2*n));
                %applico il filtro shiftato
                fttA_filtered = fftshift(butterworth) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2,imageB);
           end
           if app.FourierHpIdealeButton.Value==true
                    %faccio la trasformata
                    fftA = fft2(app.ImageFile);
                    % shifto l'immagine 
                    fttAS = fftshift(fftA);
                    % calcolo la maschera
                    [hA,  lA] = size(app.ImageFile);
                    distmatrix = app.distmatrix(hA,lA);
                    mask = 1-(app.filtro_ideale(distmatrix,35));
                    %applico il filtro shiftato
                    fttA_filtered = fftshift(mask) .* fttAS;
                    %anti shifto e trasformo l'immagine
                    imageB = ifft2(fftshift(fttA_filtered));
                    imagesc(app.UIAxes2,imageB);
           end

           if app.FourierHpGaussianoButton.Value==true
               %faccio la trasformata
                fftA = fft2(app.ImageFile);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                sigma = 30;
                [hA,  lA] = size(app.ImageFile);
                dist = app.distmatrix(hA,lA);
                gaussiano = 1-(exp(-(dist.^2)/(2*sigma^2)));
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2,imageB);
           end
           if app.FourierHpButterworthButton.Value==true
                %faccio la trasformata
                fftA = fft2(app.ImageFile);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                sigma = 35;
                n = 3;
                [hA,  lA] = size(app.ImageFile);
                dist = app.distmatrix(hA,lA);
                butterworth = 1-(1./(1+(dist./sigma).^(2*n)));
                %applico il filtro shiftato
                fttA_filtered = fftshift(butterworth) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2,imageB);

           end

           
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
            
        end

        % Button down function: OptionButtonGroup
        function OptionButtonGroupButtonDown(app, event)
            
        end

        % Callback function
        function SigmaSliderValueChanged(app, event)
            value = app.SigmaSlider.Value;
            app.ImageFile = func4(app,app.ImageFile,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Enanched')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.OptionButtonGroup.ButtonDownFcn = createCallbackFcn(app, @OptionButtonGroupButtonDown, true);
            app.FiltromediaButton = uiradiobutton(app.OptionButtonGroup);
            app.FiltromediaButton.Text = 'Filtro media';
            app.FiltromediaButton.Value = true;
            app.NonuniformeButton = uiradiobutton(app.OptionButtonGroup);
            app.NonuniformeButton.Text = 'Non uniforme';
            app.GaussianoButton = uiradiobutton(app.OptionButtonGroup);
            app.GaussianoButton.Text = 'Gaussiano';
            app.MedianoButton = uiradiobutton(app.OptionButtonGroup);
            app.MedianoButton.Text = 'Mediano';
            app.LaplacianoButton = uiradiobutton(app.OptionButtonGroup);
            app.LaplacianoButton.Text = 'Laplaciano';
            app.EdgedetectorCannyButton = uiradiobutton(app.OptionButtonGroup);
            app.EdgedetectorCannyButton.Text = 'Edge detector Canny';
            app.EdgedetectorPrewittButton = uiradiobutton(app.OptionButtonGroup);
            app.EdgedetectorPrewittButton.Text = 'Edge detector Prewitt';
            app.EdgedetectorSobelButton = uiradiobutton(app.OptionButtonGroup);
            app.EdgedetectorSobelButton.Text = 'Edge detector Sobel';
            app.FourierButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierButton.Text = 'Fourier';
            app.FourierLpIdealeButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierLpIdealeButton.Text = 'Fourier Lp Ideale';
            app.FourierLpGaussianoButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierLpGaussianoButton.Text = 'Fourier Lp Gaussiano';
            app.FourierLpButterworthButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierLpButterworthButton.Text = 'Fourier Lp Butterworth';
            app.FourierHpGaussianoButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierHpGaussianoButton.Text = 'Fourier Hp Gaussiano';
            app.FourierHpButterworthButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierHpButterworthButton.Text = 'Fourier Hp Butterworth';
            app.FourierHpIdealeButton = uiradiobutton(app.OptionButtonGroup);
            app.FourierHpIdealeButton.Text = 'Fourier Hp Ideale';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prova_Immagini_gui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end