classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        filtrineltempoTab               matlab.ui.container.Tab
        salvaimmagineButton_2           matlab.ui.control.Button
        selezionaimmagineButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        filtromediaTab                  matlab.ui.container.Tab
        grandezzamatriceSpinner_2       matlab.ui.control.Spinner
        grandezzamatriceSpinner_2Label  matlab.ui.control.Label
        filtrogaussianoTab              matlab.ui.container.Tab
        grandezzamatriceSpinner         matlab.ui.control.Spinner
        grandezzamatriceSpinnerLabel    matlab.ui.control.Label
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        filtromedianoTab                matlab.ui.container.Tab
        grandezzamatriceSpinner_3       matlab.ui.control.Spinner
        grandezzamatriceSpinner_3Label  matlab.ui.control.Label
        filtripassaaltoTab              matlab.ui.container.Tab
        applicafiltroButton             matlab.ui.control.Button
        enhancementdellimmagineButton   matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CannyButton                     matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        LaplacianoButton                matlab.ui.control.RadioButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        filtriinfrequenzaTab            matlab.ui.container.Tab
        salvaimmagineButton             matlab.ui.control.Button
        selezionaimmagineButton_2       matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        idealelpTab                     matlab.ui.container.Tab
        RaggioSpinner                   matlab.ui.control.Spinner
        RaggioSpinnerLabel              matlab.ui.control.Label
        gaussianolpTab                  matlab.ui.container.Tab
        sigmaSlider_2                   matlab.ui.control.Slider
        sigmaSlider_2Label              matlab.ui.control.Label
        butterworthlpTab                matlab.ui.container.Tab
        SigmaSpinner_3                  matlab.ui.control.Spinner
        SigmaSpinner_3Label             matlab.ui.control.Label
        ordineSpinner                   matlab.ui.control.Spinner
        ordineSpinnerLabel              matlab.ui.control.Label
        idealehpTab                     matlab.ui.container.Tab
        RaggioSpinner_2                 matlab.ui.control.Spinner
        RaggioSpinner_2Label            matlab.ui.control.Label
        enhancementdellimmagineButton_2  matlab.ui.control.Button
        visualizzazione2Button          matlab.ui.control.Button
        applicafiltroButton_2           matlab.ui.control.Button
        gaussianohpTab                  matlab.ui.container.Tab
        enhancementdellimmagineButton_3  matlab.ui.control.Button
        Visualizzazione2Button          matlab.ui.control.Button
        ApplicafiltroButton             matlab.ui.control.Button
        SigmaSpinner                    matlab.ui.control.Spinner
        SigmaSpinnerLabel               matlab.ui.control.Label
        butterworthhpTab                matlab.ui.container.Tab
        SigmaSpinner_2                  matlab.ui.control.Spinner
        SigmaSpinner_2Label             matlab.ui.control.Label
        enhacementdellimmagineButton    matlab.ui.control.Button
        visualizzazione2Button_2        matlab.ui.control.Button
        applicafiltroButton_3           matlab.ui.control.Button
        OrdineSpinner                   matlab.ui.control.Spinner
        OrdineSpinnerLabel              matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        trasformatawaveletTab           matlab.ui.container.Tab
        eseguiButton                    matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        MorletButton                    matlab.ui.control.RadioButton
        ShannonButton                   matlab.ui.control.RadioButton
        HaarButton                      matlab.ui.control.RadioButton
        selezionaimmagineButton_3       matlab.ui.control.Button
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imInput % immagine in input
        imOutput % ummagine in output
        grandezzaMatrice = 3 %inizializzo un valore di default per evitare che vada in errore
        sigma = 3 %inizializzo un valore di default per evitare che vada in errore
        currentFilter = "laplaciano" %serve per tenere traccia del filtro passa alto corrente 
        currentFilterwave="haar"
        raggio= 35
        sigmag=3
        sigmab=35   
        ordine=1
        savePath
        matrice
    end
    
    methods (Access = private)
        
        function y =  distmatrix(app,M,N)
            
                    
                    u = 0:(M - 1);
                    v = 0:(N - 1);
                    
                    ind_u = find(u > M/2);
                    u(ind_u) = u(ind_u) - M;
                    ind_v = find(v > N/2);
                    v(ind_v) = v(ind_v) - N;
                    
                    [V, U] = meshgrid(v, u);
                    
                    %calculate distance matrix
                    y = sqrt((U .^ 2) + (V .^ 2));
        end
    
        
        function [res] = filtro_ideale(app,I,dist)
            I(I <= dist) = 1;
            I(I > dist) = 0;
            res = I;
         
        end
        
        function [res] = filtro_gaussiano_frequenza(app,imageA,sigma)
            [hA,  lA] = size(imageA);
            dist = distmatrix(app,hA,lA);
            res = exp(-(dist.^2)/(2*sigma^2));
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selezionaimmagineButton
        function selezionaimmagineButtonPushed(app, event)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
            % Display uigetfile dialog
            app.UIAxes2.Visible = 'off';
            app.UIAxes2.Colormap = gray(256);
            axis(app.UIAxes2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes,app.imInput);
        end

        % Callback function
        function filtroGaussianoLPSButtonPushed(app, event)
                    dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
                    gauss_variance = app.sigma; % variance
                        H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
                    app.imOutput = imfilter(app.imInput, H_gaussian);
                    imagesc(app.UIAxes2,app.imOutput);
        end

        % Callback function
        function grandezzamatriceEditFieldValueChanged(app, event)
            value = app.grandezzamatriceEditField.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: sigmaSlider
        function sigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
            gauss_variance = changingValue; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            app.imOutput = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,app.imOutput);
             close all
            figure
            bar3(H_gaussian);
            title("Filtro gaussiano")
        end

        % Value changed function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanged(app, event)
            value = app.grandezzamatriceSpinner.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            dim_gauss_filter = [app.grandezzaMatrice  app.grandezzaMatrice ]; % dim of filter
            gauss_variance = app.sigma; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            app.imOutput = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,app.imOutput);
            close all
            figure
            bar3(H_gaussian);
            title("Filtro gaussiano")
        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            value = app.sigmaSlider.Value;
            app.sigma = value;
        end

        % Value changing function: grandezzamatriceSpinner_2
        function grandezzamatriceSpinner_MediaValueChanging(app, event)
            changingValue = event.Value;
            close all
           H_average = fspecial('average', changingValue); 
                % apply the average filter to the image
                I_average = imfilter(app.imInput, H_average);                
                app.imOutput=uint8(I_average);
                imagesc(app.UIAxes2,app.imOutput);
                figure;
                bar3(H_average);
                title("Filtro media")
        end

        % Value changing function: grandezzamatriceSpinner_3
        function grandezzamatriceSpinner_3ValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            app.imOutput = medfilt2(app.imInput, [app.grandezzaMatrice app.grandezzaMatrice]);
            imagesc(app.UIAxes2,app.imOutput);
             
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChangedHPS(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Laplaciano'
                    app.currentFilter = "laplaciano";
                case 'Prewitt'
                    app.currentFilter = "prewitt";
                case 'Sobel'
                    app.currentFilter = "sobel"; 
                case 'Canny'
                    app.currentFilter = "canny";
            end
        end

        % Button pushed function: enhancementdellimmagineButton
        function enhancedImmagineButtonPushedHPS(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    filtrata = imfilter(double_input,filter);
                    app.imOutput = uint8(double_input - filtrata);
                    imagesc(app.UIAxes2,app.imOutput);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    app.imOutput = uint8(double_input + filtered);
                    imagesc(app.UIAxes2,app.imOutput);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    app.imOutput = uint8(filtered + double_input);
                    imagesc(app.UIAxes2,app.imOutput);
                case "canny"
                    filtered = edge(double_input,'canny');
                    app.imOutput = uint8(filtered );
                    imagesc(app.UIAxes2,app.imOutput);
            end
        end

        % Button pushed function: applicafiltroButton
        function applicafiltroButtonPushed(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    app.imOutput = uint8(imfilter(double_input,filter));
                    imagesc(app.UIAxes2,app.imOutput);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    app.imOutput = uint8(filtered);
                    imagesc(app.UIAxes2,app.imOutput);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    app.imOutput = uint8(filtered);
                    imagesc(app.UIAxes2,app.imOutput);
                case "canny"
                    app.imOutput = uint8(edge(double_input,'canny'));
                    imagesc(app.UIAxes2,app.imOutput);
            end
        end

        % Button pushed function: selezionaimmagineButton_2
        function selezionaimmagineButton_2Pushed(app, event)
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_2.Colormap = gray(256);
            axis(app.UIAxes_2, 'image');
            % Display uigetfile dialog
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2_2.Colormap = gray(256);
            axis(app.UIAxes2_2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes_2,app.imInput);
        end

        % Value changed function: RaggioSpinner
        function RaggioSpinnerValueChanged(app, event)
            value = app.RaggioSpinner.Value;
            app.raggio=value;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.imInput);
                matrice= app.distmatrix(hA,lA);
                mask = app.filtro_ideale(matrice,app.raggio);
                %applico il filtro shiftato
                fttA_filtered = fftshift(mask) .* fttAS;
                %anti shifto e trasformo l'immagine
                app.imOutput = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,app.imOutput);
                close all
                figure
                imshow(fftshift(mask))
                title("Filtro ideale")
                
        end

        % Value changed function: sigmaSlider_2
        function sigmaSlider_2ValueChanged(app, event)
            value = app.sigmaSlider_2.Value;
            app.sigmag=value;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                gaussiano = app.filtro_gaussiano_frequenza(app.imInput,app.sigmag);
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                app.imOutput = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,app.imOutput);
                close all
                figure
                imshow(fftshift(gaussiano))
                title("Filtro gaussiano")

        end

        % Value changing function: ordineSpinner
        function ordineSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.ordine=changingValue;  
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.imInput);
                dist = app.distmatrix(hA,lA);
                butterworth = 1./(1+(dist./app.sigmab).^(2*app.ordine));
                %applico il filtro shiftato
                fttA_filtered = fftshift(butterworth) .* fttAS;
                %anti shifto e trasformo l'immagine
                app.imOutput = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,app.imOutput);
                close all
                figure
                imshow(fftshift(butterworth));
                title("Filtro butterworth")
        end

        % Button pushed function: visualizzazione2Button
        function visualizzazione2ButtonPushedIHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Value changed function: RaggioSpinner_2
        function RaggioSpinner_2ValueChangedIHPF(app, event)
            app.raggio = app.RaggioSpinner_2.Value;
        end

        % Button pushed function: applicafiltroButton_2
        function applicafiltroButton_2PushedIHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes2_2,app.imOutput);
            close all
            figure
            imshow(fftshift(mask))
            title("Filtro ideale")
        end

        % Value changing function: RaggioSpinner_2
        function RaggioSpinner_2ValueChangingIHPF(app, event)
            app.raggio = event.Value;
        end

        % Button pushed function: enhancementdellimmagineButton_2
        function enhancementdellimmagineButton_2PushedIHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            distmatrix = app.distmatrix(hA,lA);
            mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
            %applico il filtro shiftato
            fttA_filtered = fftshift(mask) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInput + imageB;
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Value changed function: SigmaSpinner
        function SigmaSpinnerValueChangedGHPF(app, event)
            app.sigmag = app.SigmaSpinner.Value;
        end

        % Value changing function: SigmaSpinner
        function SigmaSpinnerValueChangingGHPF(app, event)
            app.sigmag = event.Value;            
        end

        % Button pushed function: ApplicafiltroButton
        function ApplicafiltroButtonPushedGHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes2_2,app.imOutput);close all
            figure
            imshow(fftshift(gaussiano));
            title("Filtro gaussiano")
        end

        % Button pushed function: Visualizzazione2Button
        function Visualizzazione2ButtonPushedGHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Button pushed function: enhancementdellimmagineButton_3
        function enhancementdellimmagineButton_3PushedGHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            gaussiano = 1-(exp(-(dist.^2)/(2*app.sigmag^2)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(gaussiano) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInput + imageB;
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Value changed function: OrdineSpinner
        function OrdineSpinnerValueChangedBHPF(app, event)
            app.ordine = app.OrdineSpinner.Value; 
        end

        % Value changing function: OrdineSpinner
        function OrdineSpinnerValueChangingBHPF(app, event)
            app.ordine = event.Value;       
        end

        % Button pushed function: applicafiltroButton_3
        function applicafiltroButton_3PushedBHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
                
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = uint8(ifft2(fftshift(fttA_filtered)));
            imagesc(app.UIAxes2_2,app.imOutput);
            close all
            figure
            imshow(fftshift(butterworth));
            title("Filtro butterworth")
        end

        % Button pushed function: visualizzazione2Button_2
        function visualizzazione2Button_2PushedBHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
                
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Button pushed function: enhacementdellimmagineButton
        function enhacementdellimmagineButtonPushedBHPF(app, event)
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
                
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));
            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            imageB = uint8(ifft2(fftshift(fttA_filtered)));
            app.imOutput = app.imInput + imageB;
            imagesc(app.UIAxes2_2,app.imOutput);
        end

        % Value changed function: SigmaSpinner_2
        function SigmaSpinner_2ValueChangedBHPF(app, event)
            app.sigma = app.SigmaSpinner_2.Value;
        end

        % Value changing function: SigmaSpinner_2
        function SigmaSpinner_2ValueChangingBHPF(app, event)
            app.sigma = event.Value;
        end

        % Value changing function: SigmaSpinner_3
        function SigmaSpinner_3ValueChangingBLPF(app, event)
            changingValue = event.Value;
            app.sigmab=changingValue;  
            %faccio la trasformata
            fftA = fft2(app.imInput);
            % shifto l'immagine 
            fttAS = fftshift(fftA);
            % calcolo la maschera
            [hA,  lA] = size(app.imInput);
            dist = app.distmatrix(hA,lA);
            butterworth = 1./(1+(dist./app.sigmab).^(2*app.ordine));
            %applico il filtro shiftato
            fttA_filtered = fftshift(butterworth) .* fttAS;
            %anti shifto e trasformo l'immagine
            app.imOutput = ifft2(fftshift(fttA_filtered));
            imagesc(app.UIAxes2_2,app.imOutput);
            close all
            figure
            imshow(fftshift(butterworth));
            title("Filtro butterworth")
        end

        % Button pushed function: salvaimmagineButton
        function salvaimmagineButtonPushed(app, event)
            [file,path] = uiputfile('.bmp');
            file_name = fullfile(path,file);
            imwrite(app.imOutput,file_name,"bmp");
        end

        % Button pushed function: salvaimmagineButton_2
        function salvaimmagineButton_2Pushed(app, event)
            [file,path] = uiputfile('.bmp');
            file_name = fullfile(path,file);
            imwrite(app.imOutput,file_name,"bmp");
        end

        % Button pushed function: selezionaimmagineButton_3
        function selezionaimmagineButton_3Pushed(app, event)
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_3.Colormap = gray(256);
            axis(app.UIAxes_3, 'image');
            % Display uigetfile dialog
            app.UIAxes2_3.Visible = 'off';
            app.UIAxes2_3.Colormap = gray(256);
            axis(app.UIAxes2_3, 'image');
            app.UIAxes2_4.Visible = 'off';
            app.UIAxes2_4.Colormap = gray(256);
            axis(app.UIAxes2_4, 'image');
            app.UIAxes2_5.Visible = 'off';
            app.UIAxes2_5.Colormap = gray(256);
            axis(app.UIAxes2_5, 'image');
            app.UIAxes2_6.Visible = 'off';
            app.UIAxes2_6.Colormap = gray(256);
            axis(app.UIAxes2_6, 'image');
            
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes_3,app.imInput);
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch selectedButton
                case 'Haar'
                    app.currentFilterwave = "haar";
                   
                case 'Shannon'
                    app.currentFiltereave = "shan";
                    
                case 'Morlet'
                    app.currentFilterwave = "morl";
                   
            end
            
        end

        % Button down function: ButtonGroup_2
        function ButtonGroup_2ButtonDown(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilterwave
                case "haar"
                    
                    [ll,lh,hl,hh] = dwt2(app.imInput,'haar');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                    
                case "shan"
                    [ll,lh,hl,hh] = dwt2(app.imInput,'db1');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                case "morl"
                    [ll,lh,hl,hh] = dwt2(app.imInput,'morl');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                
            end
        end

        % Button pushed function: eseguiButton
        function eseguiButtonPushedWavelet(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilterwave
                case "haar"
                    
                    [ll,lh,hl,hh] = dwt2(app.imInput,'haar');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                    
                case "shan"
                    [ll,lh,hl,hh] = dwt2(app.imInput,'shan');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                case "morl"
                    [ll,lh,hl,hh] = dwt2(app.imInput,'morl');
                    imagesc(app.UIAxes2_3,ll);
                    imagesc(app.UIAxes2_5,lh);
                    imagesc(app.UIAxes2_4,hl);
                    imagesc(app.UIAxes2_6,hh);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.filtrineltempoTab = uitab(app.TabGroup);
            app.filtrineltempoTab.Title = 'filtri nel tempo';
            app.UIAxes = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes, 'INPUT')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes2, 'OUTPUT')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup2 = uitabgroup(app.filtrineltempoTab);
            app.filtromediaTab = uitab(app.TabGroup2);
            app.filtromediaTab.Title = 'filtro media ';
            app.grandezzamatriceSpinner_2Label = uilabel(app.filtromediaTab);
            app.grandezzamatriceSpinner_2Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_2 = uispinner(app.filtromediaTab);
            app.grandezzamatriceSpinner_2.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_MediaValueChanging, true);
            app.grandezzamatriceSpinner_2.Limits = [1 256];
            app.grandezzamatriceSpinner_2.Value = 3;
            app.filtrogaussianoTab = uitab(app.TabGroup2);
            app.filtrogaussianoTab.Title = 'filtro gaussiano';
            app.sigmaSliderLabel = uilabel(app.filtrogaussianoTab);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.filtrogaussianoTab);
            app.sigmaSlider.Limits = [1 50];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.ValueChangingFcn = createCallbackFcn(app, @sigmaSliderValueChanging, true);
            app.sigmaSlider.Value = 5;
            app.grandezzamatriceSpinnerLabel = uilabel(app.filtrogaussianoTab);
            app.grandezzamatriceSpinnerLabel.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner = uispinner(app.filtrogaussianoTab);
            app.grandezzamatriceSpinner.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging, true);
            app.grandezzamatriceSpinner.Limits = [1 256];
            app.grandezzamatriceSpinner.ValueChangedFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanged, true);
            app.grandezzamatriceSpinner.Value = 10;
            app.filtromedianoTab = uitab(app.TabGroup2);
            app.filtromedianoTab.Title = 'filtro mediano';
            app.grandezzamatriceSpinner_3Label = uilabel(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_3 = uispinner(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_3ValueChanging, true);
            app.grandezzamatriceSpinner_3.Limits = [2 256];
            app.grandezzamatriceSpinner_3.Value = 3;
            app.filtripassaaltoTab = uitab(app.TabGroup2);
            app.filtripassaaltoTab.Title = 'filtri passa alto ';
            app.ButtonGroup = uibuttongroup(app.filtripassaaltoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChangedHPS, true);
            app.ButtonGroup.Title = 'Button Group';
            app.LaplacianoButton = uiradiobutton(app.ButtonGroup);
            app.LaplacianoButton.Text = 'Laplaciano';
            app.LaplacianoButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.ButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uiradiobutton(app.ButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.enhancementdellimmagineButton = uibutton(app.filtripassaaltoTab, 'push');
            app.enhancementdellimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @enhancedImmagineButtonPushedHPS, true);
            app.enhancementdellimmagineButton.Text = 'enhancement dell''immagine';
            app.applicafiltroButton = uibutton(app.filtripassaaltoTab, 'push');
            app.applicafiltroButton.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButtonPushed, true);
            app.applicafiltroButton.Text = 'applica filtro';
            app.selezionaimmagineButton = uibutton(app.filtrineltempoTab, 'push');
            app.selezionaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButtonPushed, true);
            app.selezionaimmagineButton.Text = 'seleziona immagine';
            app.salvaimmagineButton_2 = uibutton(app.filtrineltempoTab, 'push');
            app.salvaimmagineButton_2.ButtonPushedFcn = createCallbackFcn(app, @salvaimmagineButton_2Pushed, true);
            app.salvaimmagineButton_2.Text = 'salva immagine';
            app.filtriinfrequenzaTab = uitab(app.TabGroup);
            app.filtriinfrequenzaTab.Title = 'filtri in frequenza';
            app.UIAxes_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes_2, 'INPUT')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes2_2, 'OUTPUT')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.TabGroup2_2 = uitabgroup(app.filtriinfrequenzaTab);
            app.idealelpTab = uitab(app.TabGroup2_2);
            app.idealelpTab.Title = 'ideale lp';
            app.RaggioSpinnerLabel = uilabel(app.idealelpTab);
            app.RaggioSpinnerLabel.Text = 'Raggio';
            app.RaggioSpinner = uispinner(app.idealelpTab);
            app.RaggioSpinner.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinnerValueChanged, true);
            app.RaggioSpinner.Value = 35;
            app.gaussianolpTab = uitab(app.TabGroup2_2);
            app.gaussianolpTab.Title = 'gaussiano lp';
            app.sigmaSlider_2Label = uilabel(app.gaussianolpTab);
            app.sigmaSlider_2Label.Text = 'sigma';
            app.sigmaSlider_2 = uislider(app.gaussianolpTab);
            app.sigmaSlider_2.Limits = [1 50];
            app.sigmaSlider_2.ValueChangedFcn = createCallbackFcn(app, @sigmaSlider_2ValueChanged, true);
            app.sigmaSlider_2.Value = 5;
            app.butterworthlpTab = uitab(app.TabGroup2_2);
            app.butterworthlpTab.Title = 'butterworth lp';
            app.ordineSpinnerLabel = uilabel(app.butterworthlpTab);
            app.ordineSpinnerLabel.Text = 'ordine';
            app.ordineSpinner = uispinner(app.butterworthlpTab);
            app.ordineSpinner.ValueChangingFcn = createCallbackFcn(app, @ordineSpinnerValueChanging, true);
            app.ordineSpinner.Limits = [1 Inf];
            app.ordineSpinner.Value = 2;
            app.SigmaSpinner_3Label = uilabel(app.butterworthlpTab);
            app.SigmaSpinner_3Label.Text = 'Sigma';
            app.SigmaSpinner_3 = uispinner(app.butterworthlpTab);
            app.SigmaSpinner_3.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinner_3ValueChangingBLPF, true);
            app.SigmaSpinner_3.Limits = [1 Inf];
            app.SigmaSpinner_3.Value = 35;
            app.idealehpTab = uitab(app.TabGroup2_2);
            app.idealehpTab.Title = 'ideale hp';
            app.applicafiltroButton_2 = uibutton(app.idealehpTab, 'push');
            app.applicafiltroButton_2.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButton_2PushedIHPF, true);
            app.applicafiltroButton_2.Text = 'applica filtro ';
            app.visualizzazione2Button = uibutton(app.idealehpTab, 'push');
            app.visualizzazione2Button.ButtonPushedFcn = createCallbackFcn(app, @visualizzazione2ButtonPushedIHPF, true);
            app.visualizzazione2Button.Text = 'visualizzazione 2';
            app.enhancementdellimmagineButton_2 = uibutton(app.idealehpTab, 'push');
            app.enhancementdellimmagineButton_2.ButtonPushedFcn = createCallbackFcn(app, @enhancementdellimmagineButton_2PushedIHPF, true);
            app.enhancementdellimmagineButton_2.Text = 'enhancement dell''immagine';
            app.RaggioSpinner_2Label = uilabel(app.idealehpTab);
            app.RaggioSpinner_2Label.Text = 'Raggio';
            app.RaggioSpinner_2 = uispinner(app.idealehpTab);
            app.RaggioSpinner_2.ValueChangingFcn = createCallbackFcn(app, @RaggioSpinner_2ValueChangingIHPF, true);
            app.RaggioSpinner_2.Limits = [1 Inf];
            app.RaggioSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinner_2ValueChangedIHPF, true);
            app.RaggioSpinner_2.Value = 35;
            app.gaussianohpTab = uitab(app.TabGroup2_2);
            app.gaussianohpTab.Title = 'gaussiano hp';
            app.SigmaSpinnerLabel = uilabel(app.gaussianohpTab);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.gaussianohpTab);
            app.SigmaSpinner.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinnerValueChangingGHPF, true);
            app.SigmaSpinner.Limits = [1 Inf];
            app.SigmaSpinner.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinnerValueChangedGHPF, true);
            app.SigmaSpinner.Value = 35;
            app.ApplicafiltroButton = uibutton(app.gaussianohpTab, 'push');
            app.ApplicafiltroButton.ButtonPushedFcn = createCallbackFcn(app, @ApplicafiltroButtonPushedGHPF, true);
            app.ApplicafiltroButton.Text = 'Applica filtro';
            app.Visualizzazione2Button = uibutton(app.gaussianohpTab, 'push');
            app.Visualizzazione2Button.ButtonPushedFcn = createCallbackFcn(app, @Visualizzazione2ButtonPushedGHPF, true);
            app.Visualizzazione2Button.Text = 'Visualizzazione 2';
            app.enhancementdellimmagineButton_3 = uibutton(app.gaussianohpTab, 'push');
            app.enhancementdellimmagineButton_3.ButtonPushedFcn = createCallbackFcn(app, @enhancementdellimmagineButton_3PushedGHPF, true);
            app.enhancementdellimmagineButton_3.Text = 'enhancement dell''immagine ';
            app.butterworthhpTab = uitab(app.TabGroup2_2);
            app.butterworthhpTab.Title = 'butterworth hp';
            app.OrdineSpinnerLabel = uilabel(app.butterworthhpTab);
            app.OrdineSpinnerLabel.Text = 'Ordine';
            app.OrdineSpinner = uispinner(app.butterworthhpTab);
            app.OrdineSpinner.ValueChangingFcn = createCallbackFcn(app, @OrdineSpinnerValueChangingBHPF, true);
            app.OrdineSpinner.Limits = [1 Inf];
            app.OrdineSpinner.ValueChangedFcn = createCallbackFcn(app, @OrdineSpinnerValueChangedBHPF, true);
            app.OrdineSpinner.Value = 2;
            app.applicafiltroButton_3 = uibutton(app.butterworthhpTab, 'push');
            app.applicafiltroButton_3.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButton_3PushedBHPF, true);
            app.applicafiltroButton_3.Text = 'applica filtro';
            app.visualizzazione2Button_2 = uibutton(app.butterworthhpTab, 'push');
            app.visualizzazione2Button_2.ButtonPushedFcn = createCallbackFcn(app, @visualizzazione2Button_2PushedBHPF, true);
            app.visualizzazione2Button_2.Text = 'visualizzazione 2';
            app.enhacementdellimmagineButton = uibutton(app.butterworthhpTab, 'push');
            app.enhacementdellimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @enhacementdellimmagineButtonPushedBHPF, true);
            app.enhacementdellimmagineButton.Text = 'enhacement dell''immagine';
            app.SigmaSpinner_2Label = uilabel(app.butterworthhpTab);
            app.SigmaSpinner_2Label.Text = 'Sigma';
            app.SigmaSpinner_2 = uispinner(app.butterworthhpTab);
            app.SigmaSpinner_2.ValueChangingFcn = createCallbackFcn(app, @SigmaSpinner_2ValueChangingBHPF, true);
            app.SigmaSpinner_2.Limits = [1 Inf];
            app.SigmaSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SigmaSpinner_2ValueChangedBHPF, true);
            app.SigmaSpinner_2.Value = 35;
            app.selezionaimmagineButton_2 = uibutton(app.filtriinfrequenzaTab, 'push');
            app.selezionaimmagineButton_2.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButton_2Pushed, true);
            app.selezionaimmagineButton_2.Text = 'seleziona immagine';
            app.salvaimmagineButton = uibutton(app.filtriinfrequenzaTab, 'push');
            app.salvaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @salvaimmagineButtonPushed, true);
            app.salvaimmagineButton.Text = 'salva immagine';
            app.trasformatawaveletTab = uitab(app.TabGroup);
            app.trasformatawaveletTab.Title = 'trasformata wavelet';
            app.UIAxes_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_3, 'INPUT')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes2_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_3, 'LL')
            app.UIAxes2_3.XLim = [0 1];
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_4 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_4, 'HL')
            app.UIAxes2_4.XLim = [0 1];
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.UIAxes2_5 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_5, 'LH')
            app.UIAxes2_5.XLim = [0 1];
            app.UIAxes2_5.XTick = [];
            app.UIAxes2_5.YTick = [];
            app.UIAxes2_6 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_6, 'HH')
            app.UIAxes2_6.XLim = [0 1];
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.selezionaimmagineButton_3 = uibutton(app.trasformatawaveletTab, 'push');
            app.selezionaimmagineButton_3.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButton_3Pushed, true);
            app.selezionaimmagineButton_3.Text = 'seleziona immagine';
            app.ButtonGroup_2 = uibuttongroup(app.trasformatawaveletTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = 'Button Group';
            app.ButtonGroup_2.ButtonDownFcn = createCallbackFcn(app, @ButtonGroup_2ButtonDown, true);
            app.HaarButton = uiradiobutton(app.ButtonGroup_2);
            app.HaarButton.Text = 'Haar';
            app.HaarButton.Value = true;
            app.ShannonButton = uiradiobutton(app.ButtonGroup_2);
            app.ShannonButton.Text = 'Shannon';
            app.MorletButton = uiradiobutton(app.ButtonGroup_2);
            app.MorletButton.Text = 'Morlet';
            app.eseguiButton = uibutton(app.trasformatawaveletTab, 'push');
            app.eseguiButton.ButtonPushedFcn = createCallbackFcn(app, @eseguiButtonPushedWavelet, true);
            app.eseguiButton.Text = 'esegui';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end